classdef auto_corr_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GenerateDataTab                 matlab.ui.container.Tab
        TransMat                        matlab.ui.control.Table
        TransitionRateMatrixflowsLabel  matlab.ui.control.Label
        LengthofTracessLabel            matlab.ui.control.Label
        LengthTraceNum                  matlab.ui.control.Label
        Rates                           matlab.ui.control.Table
        EmissionRatesRNAsLabel          matlab.ui.control.Label
        BootstrapsCheckBox              matlab.ui.control.CheckBox
        InitProbs                       matlab.ui.control.Table
        InitialProbabilityDistributionLabel  matlab.ui.control.Label
        ElongationTimesEditFieldLabel   matlab.ui.control.Label
        ElongationTimesEditField        matlab.ui.control.NumericEditField
        TimeResolutionsEditFieldLabel   matlab.ui.control.Label
        TimeResolutionsEditField        matlab.ui.control.NumericEditField
        LengthofTracespointsEditFieldLabel  matlab.ui.control.Label
        LengthofTracespointsEditField   matlab.ui.control.NumericEditField
        NumberofTracesEditFieldLabel    matlab.ui.control.Label
        NumberofTracesEditField         matlab.ui.control.NumericEditField
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofStatesEditField         matlab.ui.control.NumericEditField
        fluoRNAEditFieldLabel           matlab.ui.control.Label
        fluoRNAEditField                matlab.ui.control.NumericEditField
        GenerateListBoxLabel            matlab.ui.control.Label
        GenerateListBox                 matlab.ui.control.ListBox
        RiseTimesEditFieldLabel         matlab.ui.control.Label
        RiseTimesEditField              matlab.ui.control.NumericEditField
        NoiseEditFieldLabel             matlab.ui.control.Label
        NoiseEditField                  matlab.ui.control.NumericEditField
        MaxDelayEditFieldLabel          matlab.ui.control.Label
        MaxDelayEditField               matlab.ui.control.NumericEditField
        CutEditFieldLabel               matlab.ui.control.Label
        CutEditField                    matlab.ui.control.NumericEditField
        Learning1Tab                    matlab.ui.container.Tab
        GuessElongationTimeCheckBox     matlab.ui.control.CheckBox
        UseRealParamsCheckBox           matlab.ui.control.CheckBox
        ElongationTimeGuessesLabel      matlab.ui.control.Label
        MinimumEditFieldLabel           matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        RiseTimeGuessesLabel            matlab.ui.control.Label
        MinimumEditField_2Label         matlab.ui.control.Label
        MinimumEditField_2              matlab.ui.control.NumericEditField
        MaximumEditField_2Label         matlab.ui.control.Label
        MaximumEditField_2              matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        StepEditField_2                 matlab.ui.control.NumericEditField
        NumberofStatesEditField_2Label  matlab.ui.control.Label
        NumberofStatesEditField_2       matlab.ui.control.NumericEditField
        TransMatTest                    matlab.ui.control.Table
        TransitionRateMatrixflowsLabel_2  matlab.ui.control.Label
        fluoRNAEditField_2Label         matlab.ui.control.Label
        fluoRNAEditField_2              matlab.ui.control.NumericEditField
        RatesTest                       matlab.ui.control.Table
        EmissionRatesRNAsLabel_2        matlab.ui.control.Label
        InitProbsTest                   matlab.ui.control.Table
        InitialProbabilityDistributionLabel_2  matlab.ui.control.Label
        ElongationTimeEstimateEditFieldLabel  matlab.ui.control.Label
        ElongationTimeEstimateEditField  matlab.ui.control.NumericEditField
        RiseTimeEstimateEditFieldLabel  matlab.ui.control.Label
        RiseTimeEstimateEditField       matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        ErrorsLabel                     matlab.ui.control.Label
        Learning2Tab                    matlab.ui.container.Tab
        GuessElongationTimeCheckBox_2   matlab.ui.control.CheckBox
        Equationacbx1aelong_termelongationtimerisetimeLabel  matlab.ui.control.Label
        InitialGuessesLabel             matlab.ui.control.Label
        aminimumEditFieldLabel          matlab.ui.control.Label
        aminimumEditField               matlab.ui.control.NumericEditField
        bminimumEditFieldLabel          matlab.ui.control.Label
        bminimumEditField               matlab.ui.control.NumericEditField
        bmaximumEditFieldLabel          matlab.ui.control.Label
        bmaximumEditField               matlab.ui.control.NumericEditField
        ResultsLabel                    matlab.ui.control.Label
        elongationtimeEditField_2Label  matlab.ui.control.Label
        elongationtimeEditField_2       matlab.ui.control.NumericEditField
        risetimeEditField_2Label        matlab.ui.control.Label
        risetimeEditField_2             matlab.ui.control.NumericEditField
        amaximumEditFieldLabel          matlab.ui.control.Label
        amaximumEditField               matlab.ui.control.NumericEditField
        elongationtimeminimumEditFieldLabel  matlab.ui.control.Label
        elongationtimeminimumEditField  matlab.ui.control.NumericEditField
        elongationtimemaximumEditFieldLabel  matlab.ui.control.Label
        elongationtimemaximumEditField  matlab.ui.control.NumericEditField
        risetimeminimumEditFieldLabel   matlab.ui.control.Label
        risetimeminimumEditField        matlab.ui.control.NumericEditField
        risetimemaximumEditFieldLabel   matlab.ui.control.Label
        risetimemaximumEditField        matlab.ui.control.NumericEditField
        NumberofEigenvaluesEditFieldLabel  matlab.ui.control.Label
        NumberofEigenvaluesEditField    matlab.ui.control.NumericEditField
        UITable2                        matlab.ui.control.Table
        GeneratePlotsButton             matlab.ui.control.Button
        LoadPlotsButton                 matlab.ui.control.Button
    end


    methods (Access = private)
    
        function learn1(app,auto_cor)
            
            time_res = app.TimeResolutionsEditField.Value;
            points_per_trace = app.LengthofTracespointsEditField.Value;
            num_traces = app.NumberofTracesEditField.Value;
            
            elong_min = app.MinimumEditField.Value;
            elong_max = app.MaximumEditField.Value;
            elong_step = app.StepEditField.Value;
            rise_min = app.MinimumEditField_2.Value;
            rise_max = app.MaximumEditField_2.Value;
            rise_step = app.StepEditField_2.Value;
            if app.UseRealParamsCheckBox.Value
                num_states = app.NumberofStatesEditField.Value;
                trans_mat = app.TransMat.Data;
                rna_per_sec = app.Rates.Data;
                fluo_per_rna = app.fluoRNAEditField.Value;
                init_dist = app.InitProbs.Data;                
            else
                num_states = app.NumberofStatesEditField_2.Value;
                trans_mat = app.TransMatTest.Data;
                rna_per_sec = app.RatesTest.Data;
                fluo_per_rna = app.fluoRNAEditField_2.Value;
                init_dist = app.InitProbsTest.Data;
            end
            [elong,rise,all] = grid_monte_carlo(time_res, points_per_trace, num_traces, num_states, ...
                          trans_mat, rna_per_sec, fluo_per_rna, init_dist, auto_cor, elong_min, ...
                          elong_max, elong_step, rise_min, rise_max, rise_step);
            app.ElongationTimeEstimateEditField.Value = elong;
            app.RiseTimeEstimateEditField.Value = rise;
            rows = elong_min:elong_step:elong_max;
            cols = rise_min:rise_step:rise_max;
            app.UITable.RowName = rows;
            app.UITable.ColumnName = cols;
            app.UITable.Data = all;
        end
        
        function learn2(app,auto_cor,hs)
%             upper = [app.amaximumEditField.Value, app.bmaximumEditField.Value, app.mean2maximumEditField.Value, ...
%                   app.elongationtimemaximumEditField.Value, app.risetimemaximumEditField.Value];
%             lower = [app.aminimumEditField.Value, app.bminimumEditField.Value, app.mean2minimumEditField.Value, ...
%                   app.elongationtimeminimumEditField.Value, app.risetimeminimumEditField.Value];
%             x = decay_line_fit(auto_cor, upper, lower);
            eigs = app.NumberofEigenvaluesEditField.Value;
            upper = [app.elongationtimemaximumEditField.Value, app.risetimemaximumEditField.Value, ...
                ones(1,eigs) * app.amaximumEditField.Value, ones(1,eigs) * app.bmaximumEditField.Value];
            lower = [app.elongationtimeminimumEditField.Value, app.risetimeminimumEditField.Value, ...
                ones(1,eigs) * app.aminimumEditField.Value, ones(1,eigs) * app.bminimumEditField.Value];
            
            if ~isempty(hs)
                x = decay_line_fit2(auto_cor, upper, lower,hs);
            else
                x = decay_line_fit2(auto_cor, upper, lower);
            end
%             app.aEditField_2.Value = x(1);
%             app.bEditField_2.Value = x(2);
%             app.elongationtimeEditField_2.Value = x(4);
%             app.risetimeEditField_2.Value = x(5);
%             app.mean2EditField.Value = x(3);
            eigs_results = zeros(eigs,2);
            eigs_results(:,1) = x(3:2 + eigs);
            eigs_results(:,2) = x(3 + eigs:end);
            app.UITable2.Data = eigs_results;
            app.elongationtimeEditField_2.Value = x(1);
            app.risetimeEditField_2.Value = x(2);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TransMat.Data = [-0.0115,  0.0095,  0.0000; ...
                                 0.0115, -0.0180,  0.0440; ...
                                 0.0000,  0.0085, -0.0440];
            app.Rates.Data = [.001,.2,.4];
            app.InitProbs.Data = [1,0,0];
            
            app.TransMatTest.Data = app.TransMat.Data;
            app.RatesTest.Data = app.Rates.Data;
            app.InitProbsTest.Data = app.InitProbs.Data;
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Value changed function: NumberofStatesEditField
        function NumberofStatesEditFieldValueChanged(app, event)
            value = app.NumberofStatesEditField.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.TransMat.ColumnName = cols;
            app.TransMat.Data = abs(normrnd(0,.1,[value,value]));
            for v = 1:value
                app.TransMat.Data(v,v) = 0;
                app.TransMat.Data(v,v) = -sum(app.TransMat.Data(:,v));
            end
            
            %resets emission rates table
            app.Rates.ColumnName = cols;
            dat = random('uniform',0,1,[1,value-1]);
            app.Rates.Data = [.0001, sort(dat)];
            
            %resets intial probability distribution table
            app.InitProbs.ColumnName = cols;
            dat = random('uniform',0,1,[1,value]);
            dat = dat / sum(dat);
            app.InitProbs.Data = dat;
        end

        % Value changed function: LengthofTracespointsEditField
        function LengthofTracespointsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Value changed function: TimeResolutionsEditField
        function TimeResolutionsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Button pushed function: LoadPlotsButton
        function LoadPlotsButtonPushed(app, event)
            [filename,pathname] = uigetfile;
            filepath = [pathname filename];
            fig = openfig(filepath);
            h = findobj(gca, 'Type', 'line');
            if isempty(h)
                h = findobj(gca, 'Type', 'errorbar');
            end
            auto_cor = get(h, 'Ydata');
            close(fig);
            
            %makes sure things are normalized
            auto_cor = auto_cor / auto_cor(1);
            %plots the imported data's correlation functions
            cor_ops = app.GenerateListBox.Value;
            hs = [];
            if ismember('Autocorrelation',cor_ops)
                h = figure();
                plot(0:length(auto_cor)-1,auto_cor);
                title('Central Moment');
                xlabel('Time Delay');
                grid on
                hs(1) = h;
            end
            first_deriv = auto_cor(2:end) - auto_cor(1:end-1);
            if ismember('First Derivative',cor_ops)
                h = figure();
                plot(first_deriv);
                title('First Derivative');
                xlabel('Time Delay');
                grid on
                hs(2) = h;
            end
            if ismember('Second Derivative', cor_ops)
                h = figure();
                plot(first_deriv(2:end) - first_deriv(1:end-1));
                title('Second Derivative');
                xlabel('Time Delay');
                grid on
                hs(3) = h;
            end
            
            
            if app.GuessElongationTimeCheckBox.Value
                learn1(app, auto_cor);
            end
            if app.GuessElongationTimeCheckBox_2.Value
                learn2(app, auto_cor,hs);
            end
        end

        % Button pushed function: GeneratePlotsButton
        function GeneratePlotsButtonPushed(app, event)
            elong_time = app.ElongationTimesEditField.Value;
            time_res = app.TimeResolutionsEditField.Value;
            points_per_trace = app.LengthofTracespointsEditField.Value;
            num_traces = app.NumberofTracesEditField.Value;
            trans_mat = app.TransMat.Data;
            num_states = app.NumberofStatesEditField.Value;
            fluo_per_rna = app.fluoRNAEditField.Value;
            rna_per_sec = app.Rates.Data;
            init_dist = app.InitProbs.Data;
            rise_time = app.RiseTimesEditField.Value;
            noise = app.NoiseEditField.Value;
            addpath('src/');
            addpath('src/utilities/');
            traces = gen_data(elong_time, time_res, points_per_trace, num_traces, num_states, ...
                              trans_mat, rna_per_sec, fluo_per_rna, rise_time, init_dist, ...
                              noise);
            
            cor_ops = app.GenerateListBox.Value;
            auto = false;
            first = false;
            second = false;
            if ismember('Autocorrelation',cor_ops)
                auto = true;
            end
            if ismember('First Derivative',cor_ops)
                first = true;
            end
            if ismember('Second Derivative', cor_ops)
                second = true;
            end
            max_delay = app.MaxDelayEditField.Value;
            cut = app.CutEditField.Value;
            bootstraps = app.BootstrapsCheckBox.Value;
            hs = gen_auto_cor(traces, auto, first, second, bootstraps, max_delay,cut);
            auto_cor = auto_corr_m_calc_norm(traces, max_delay);
            % estimates elongation/rise time using method 1 if box is checked
            if app.GuessElongationTimeCheckBox.Value             
                learn1(app, auto_cor);
            end
            
            % estimates elongation time using method 2 if the box is checked
            if app.GuessElongationTimeCheckBox_2.Value
                learn2(app, auto_cor, hs);
            end
            
        end

        % Value changed function: NumberofStatesEditField_2
        function NumberofStatesEditField_2ValueChanged(app, event)
            value = app.NumberofStatesEditField_2.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.TransMatTest.ColumnName = cols;
            app.TransMatTest.Data = abs(normrnd(0,.1,[value,value]));
            for v = 1:value
                app.TransMatTest.Data(v,v) = 0;
                app.TransMatTest.Data(v,v) = -sum(app.TransMatTest.Data(:,v));
            end
            
            %resets emission rates table
            app.RatesTest.ColumnName = cols;
            dat = random('uniform',0,1,[1,value-1]);
            app.RatesTest.Data = [.0001, sort(dat)];
            
            %resets intial probability distribution table
            app.InitProbsTest.ColumnName = cols;
            dat = random('uniform',0,1,[1,value]);
            dat = dat / sum(dat);
            app.InitProbsTest.Data = dat;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenerateDataTab = uitab(app.TabGroup);
            app.GenerateDataTab.Title = 'Generate Data';
            app.TransMat = uitable(app.GenerateDataTab);
            app.TransMat.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.TransMat.RowName = {};
            app.TransMat.ColumnEditable = true;
            app.TransitionRateMatrixflowsLabel = uilabel(app.GenerateDataTab);
            app.TransitionRateMatrixflowsLabel.Text = 'Transition Rate Matrix (flow/s)';
            app.LengthofTracessLabel = uilabel(app.GenerateDataTab);
            app.LengthofTracessLabel.Text = 'Length of Traces (s)';
            app.LengthTraceNum = uilabel(app.GenerateDataTab);
            app.LengthTraceNum.Text = '2000';
            app.Rates = uitable(app.GenerateDataTab);
            app.Rates.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.Rates.RowName = {};
            app.Rates.ColumnEditable = true;
            app.EmissionRatesRNAsLabel = uilabel(app.GenerateDataTab);
            app.EmissionRatesRNAsLabel.Text = 'Emission Rates (RNA/s)';
            app.BootstrapsCheckBox = uicheckbox(app.GenerateDataTab);
            app.BootstrapsCheckBox.Text = 'Bootstraps';
            app.InitProbs = uitable(app.GenerateDataTab);
            app.InitProbs.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.InitProbs.RowName = {};
            app.InitProbs.ColumnEditable = true;
            app.InitialProbabilityDistributionLabel = uilabel(app.GenerateDataTab);
            app.InitialProbabilityDistributionLabel.Text = 'Initial Probability Distribution';
            app.ElongationTimesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.ElongationTimesEditFieldLabel.Text = 'Elongation Time (s)';
            app.ElongationTimesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.ElongationTimesEditField.Limits = [0 Inf];
            app.ElongationTimesEditField.Value = 160;
            app.TimeResolutionsEditFieldLabel = uilabel(app.GenerateDataTab);
            app.TimeResolutionsEditFieldLabel.Text = 'Time Resolution (s)';
            app.TimeResolutionsEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.TimeResolutionsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeResolutionsEditFieldValueChanged, true);
            app.TimeResolutionsEditField.Value = 20;
            app.LengthofTracespointsEditFieldLabel = uilabel(app.GenerateDataTab);
            app.LengthofTracespointsEditFieldLabel.Text = 'Length of Traces (points)';
            app.LengthofTracespointsEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.LengthofTracespointsEditField.Limits = [1 Inf];
            app.LengthofTracespointsEditField.ValueDisplayFormat = '%.0f';
            app.LengthofTracespointsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofTracespointsEditFieldValueChanged, true);
            app.LengthofTracespointsEditField.Value = 100;
            app.NumberofTracesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NumberofTracesEditFieldLabel.Text = 'Number of Traces ';
            app.NumberofTracesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NumberofTracesEditField.Limits = [1 Inf];
            app.NumberofTracesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTracesEditField.Value = 50;
            app.NumberofStatesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States';
            app.NumberofStatesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NumberofStatesEditField.Limits = [0 Inf];
            app.NumberofStatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditFieldValueChanged, true);
            app.NumberofStatesEditField.Value = 3;
            app.fluoRNAEditFieldLabel = uilabel(app.GenerateDataTab);
            app.fluoRNAEditFieldLabel.Text = 'fluo/RNA';
            app.fluoRNAEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.fluoRNAEditField.Limits = [0 Inf];
            app.fluoRNAEditField.Value = 350;
            app.GenerateListBoxLabel = uilabel(app.GenerateDataTab);
            app.GenerateListBoxLabel.Text = 'Generate';
            app.GenerateListBox = uilistbox(app.GenerateDataTab);
            app.GenerateListBox.Items = {'Autocorrelation', 'First Derivative', 'Second Derivative'};
            app.GenerateListBox.Multiselect = 'on';
            app.GenerateListBox.Value = {'Autocorrelation'};
            app.RiseTimesEditFieldLabel = uilabel(app.GenerateDataTab);
            app.RiseTimesEditFieldLabel.Text = 'Rise Time (s)';
            app.RiseTimesEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.RiseTimesEditField.Limits = [0 Inf];
            app.RiseTimesEditField.Value = 40;
            app.NoiseEditFieldLabel = uilabel(app.GenerateDataTab);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.NoiseEditField.Limits = [0 Inf];
            app.MaxDelayEditFieldLabel = uilabel(app.GenerateDataTab);
            app.MaxDelayEditFieldLabel.Text = 'Max Delay';
            app.MaxDelayEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.MaxDelayEditField.Limits = [1 Inf];
            app.MaxDelayEditField.Value = 20;
            app.CutEditFieldLabel = uilabel(app.GenerateDataTab);
            app.CutEditFieldLabel.Text = 'Cut';
            app.CutEditField = uieditfield(app.GenerateDataTab, 'numeric');
            app.CutEditField.Limits = [0 Inf];
            app.Learning1Tab = uitab(app.TabGroup);
            app.Learning1Tab.Title = 'Learning 1';
            app.GuessElongationTimeCheckBox = uicheckbox(app.Learning1Tab);
            app.GuessElongationTimeCheckBox.Text = 'Guess Elongation Time';
            app.UseRealParamsCheckBox = uicheckbox(app.Learning1Tab);
            app.UseRealParamsCheckBox.Text = 'Use Real Params';
            app.ElongationTimeGuessesLabel = uilabel(app.Learning1Tab);
            app.ElongationTimeGuessesLabel.Text = 'Elongation Time Guesses';
            app.MinimumEditFieldLabel = uilabel(app.Learning1Tab);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.MinimumEditField.Limits = [0 Inf];
            app.MinimumEditField.Value = 120;
            app.MaximumEditFieldLabel = uilabel(app.Learning1Tab);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.MaximumEditField.Limits = [0 Inf];
            app.MaximumEditField.Value = 200;
            app.StepEditFieldLabel = uilabel(app.Learning1Tab);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.Value = 10;
            app.RiseTimeGuessesLabel = uilabel(app.Learning1Tab);
            app.RiseTimeGuessesLabel.Text = 'Rise Time Guesses';
            app.MinimumEditField_2Label = uilabel(app.Learning1Tab);
            app.MinimumEditField_2Label.Text = 'Minimum';
            app.MinimumEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.MinimumEditField_2.Limits = [0 Inf];
            app.MaximumEditField_2Label = uilabel(app.Learning1Tab);
            app.MaximumEditField_2Label.Text = 'Maximum';
            app.MaximumEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.MaximumEditField_2.Limits = [0 Inf];
            app.MaximumEditField_2.Value = 40;
            app.StepEditField_2Label = uilabel(app.Learning1Tab);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.StepEditField_2.Value = 5;
            app.NumberofStatesEditField_2Label = uilabel(app.Learning1Tab);
            app.NumberofStatesEditField_2Label.Text = 'Number of States';
            app.NumberofStatesEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.NumberofStatesEditField_2.Limits = [0 Inf];
            app.NumberofStatesEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditField_2ValueChanged, true);
            app.NumberofStatesEditField_2.Value = 3;
            app.TransMatTest = uitable(app.Learning1Tab);
            app.TransMatTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.TransMatTest.RowName = {};
            app.TransMatTest.ColumnEditable = true;
            app.TransitionRateMatrixflowsLabel_2 = uilabel(app.Learning1Tab);
            app.TransitionRateMatrixflowsLabel_2.Text = 'Transition Rate Matrix (flow/s)';
            app.fluoRNAEditField_2Label = uilabel(app.Learning1Tab);
            app.fluoRNAEditField_2Label.Text = 'fluo/RNA';
            app.fluoRNAEditField_2 = uieditfield(app.Learning1Tab, 'numeric');
            app.fluoRNAEditField_2.Limits = [0 Inf];
            app.fluoRNAEditField_2.Value = 350;
            app.RatesTest = uitable(app.Learning1Tab);
            app.RatesTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.RatesTest.RowName = {};
            app.RatesTest.ColumnEditable = true;
            app.EmissionRatesRNAsLabel_2 = uilabel(app.Learning1Tab);
            app.EmissionRatesRNAsLabel_2.Text = 'Emission Rates (RNA/s)';
            app.InitProbsTest = uitable(app.Learning1Tab);
            app.InitProbsTest.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.InitProbsTest.RowName = {};
            app.InitProbsTest.ColumnEditable = true;
            app.InitialProbabilityDistributionLabel_2 = uilabel(app.Learning1Tab);
            app.InitialProbabilityDistributionLabel_2.Text = 'Initial Probability Distribution';
            app.ElongationTimeEstimateEditFieldLabel = uilabel(app.Learning1Tab);
            app.ElongationTimeEstimateEditFieldLabel.Text = 'Elongation Time Estimate';
            app.ElongationTimeEstimateEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.ElongationTimeEstimateEditField.Editable = 'off';
            app.RiseTimeEstimateEditFieldLabel = uilabel(app.Learning1Tab);
            app.RiseTimeEstimateEditFieldLabel.Text = 'Rise Time Estimate';
            app.RiseTimeEstimateEditField = uieditfield(app.Learning1Tab, 'numeric');
            app.RiseTimeEstimateEditField.Editable = 'off';
            app.UITable = uitable(app.Learning1Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ErrorsLabel = uilabel(app.Learning1Tab);
            app.ErrorsLabel.Text = 'Errors';
            app.Learning2Tab = uitab(app.TabGroup);
            app.Learning2Tab.Title = 'Learning 2';
            app.GuessElongationTimeCheckBox_2 = uicheckbox(app.Learning2Tab);
            app.GuessElongationTimeCheckBox_2.Text = 'Guess Elongation Time';
            app.Equationacbx1aelong_termelongationtimerisetimeLabel = uilabel(app.Learning2Tab);
            app.Equationacbx1aelong_termelongationtimerisetimeLabel.Text = 'Equation: poisson term(elongation time,rise time) + a * dynamics term(elongation time, rise time, b)';
            app.InitialGuessesLabel = uilabel(app.Learning2Tab);
            app.InitialGuessesLabel.Text = 'Initial Guesses';
            app.aminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.aminimumEditFieldLabel.Text = 'a minimum';
            app.aminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.bminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.bminimumEditFieldLabel.Text = 'b minimum';
            app.bminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.bminimumEditField.Value = 0.01;
            app.bmaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.bmaximumEditFieldLabel.Text = 'b maximum';
            app.bmaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.bmaximumEditField.Value = 5;
            app.ResultsLabel = uilabel(app.Learning2Tab);
            app.ResultsLabel.Text = 'Results';
            app.elongationtimeEditField_2Label = uilabel(app.Learning2Tab);
            app.elongationtimeEditField_2Label.Text = 'elongation time';
            app.elongationtimeEditField_2 = uieditfield(app.Learning2Tab, 'numeric');
            app.elongationtimeEditField_2.Editable = 'off';
            app.risetimeEditField_2Label = uilabel(app.Learning2Tab);
            app.risetimeEditField_2Label.Text = 'rise time';
            app.risetimeEditField_2 = uieditfield(app.Learning2Tab, 'numeric');
            app.risetimeEditField_2.Editable = 'off';
            app.amaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.amaximumEditFieldLabel.Text = 'a maximum';
            app.amaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.amaximumEditField.Value = 100;
            app.elongationtimeminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.elongationtimeminimumEditFieldLabel.Text = 'elongation time minimum';
            app.elongationtimeminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.elongationtimeminimumEditField.Value = 4;
            app.elongationtimemaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.elongationtimemaximumEditFieldLabel.Text = 'elongation time maximum';
            app.elongationtimemaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.elongationtimemaximumEditField.Value = 12;
            app.risetimeminimumEditFieldLabel = uilabel(app.Learning2Tab);
            app.risetimeminimumEditFieldLabel.Text = 'rise time minimum';
            app.risetimeminimumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.risetimeminimumEditField.Value = 0.01;
            app.risetimemaximumEditFieldLabel = uilabel(app.Learning2Tab);
            app.risetimemaximumEditFieldLabel.Text = 'rise time maximum';
            app.risetimemaximumEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.risetimemaximumEditField.Value = 6;
            app.NumberofEigenvaluesEditFieldLabel = uilabel(app.Learning2Tab);
            app.NumberofEigenvaluesEditFieldLabel.Text = 'Number of Eigenvalues';
            app.NumberofEigenvaluesEditField = uieditfield(app.Learning2Tab, 'numeric');
            app.NumberofEigenvaluesEditField.Limits = [1 Inf];
            app.NumberofEigenvaluesEditField.Value = 1;
            app.UITable2 = uitable(app.Learning2Tab);
            app.UITable2.ColumnName = {'a'; 'b'};
            app.UITable2.RowName = {};
            app.GeneratePlotsButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotsButtonPushed, true);
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.LoadPlotsButton = uibutton(app.UIFigure, 'push');
            app.LoadPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotsButtonPushed, true);
            app.LoadPlotsButton.Text = 'Load Plots';
        end
    end

    methods (Access = public)

        % Construct app
        function app = auto_corr_gen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end